classdef stress_plotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFig                     matlab.ui.Figure
        ImportMenu                matlab.ui.container.Menu
        CoulombMenu               matlab.ui.container.Menu
        FaultMenu                 matlab.ui.container.Menu
        InterseismicMenu          matlab.ui.container.Menu
        RunMenu                   matlab.ui.container.Menu
        CalcMenu                  matlab.ui.container.Menu
        PlotPanel                 matlab.ui.container.Panel
        colormapDD                matlab.ui.control.DropDown
        colormapDropDownLabel     matlab.ui.control.Label
        ExpFigDD                  matlab.ui.control.DropDown
        AutomaticallyexportfiguresDropDownLabel  matlab.ui.control.Label
        plotdateCB                matlab.ui.control.CheckBox
        plot3dCB                  matlab.ui.control.CheckBox
        CumulativeStressPanel     matlab.ui.container.Panel
        prestressSp               matlab.ui.control.Spinner
        prestressMPaSpinnerLabel  matlab.ui.control.Label
        interseismicCB            matlab.ui.control.CheckBox
        enddateSp                 matlab.ui.control.Spinner
        enddateSpinnerLabel       matlab.ui.control.Label
        startdateSp               matlab.ui.control.Spinner
        startdateSpinnerLabel     matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        EarthquakesTab            matlab.ui.container.Tab
        EventTable                matlab.ui.control.Table
        FaultsTab                 matlab.ui.container.Tab
        FaultTable                matlab.ui.control.Table
    end

    
    properties (Access = public)
        coul_dir % directory containing coulomb files
        fault_dir %directory containing fault geometry files
        interseis_file %csv file with annual interseismic CST
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: CoulombMenu
        function import_coulomb(app, event)
            %import coulomb files and fill EventTable
            set(app.UIFig,'Visible','off')
            app.coul_dir = uigetdir;
            set(app.UIFig,'Visible','on')
            number_of_files = numel(dir([app.coul_dir '\*.csv']));
            events = strings(number_of_files,1);
            years = nan(number_of_files,1);
            months = nan(number_of_files,1);
            days = nan(number_of_files,1);
            scenarios = nan(number_of_files,1);
            plot = true(number_of_files,1);
            %skip = false(number_of_files,1);
            coulomb_files = ls([app.coul_dir '\*.csv']);
            for i = 1:number_of_files
                file_name = strrep(coulomb_files(i,:),' ',''); %extract fault name from csv file names
                event_name = convertCharsToStrings(file_name(1:end-4));
                events(i) = event_name;
                year = str2double(convertCharsToStrings(file_name(1:4)));
                month = str2double(convertCharsToStrings(file_name(5:6)));
                day = str2double(convertCharsToStrings(file_name(7:8)));
                scenario = str2double(convertCharsToStrings(file_name(10:11)));
                if isnan(scenario)
                    scenario = 1;
                end
                years(i) = year;
                months(i) = month;
                days(i) = day;
                scenarios(i) = scenario;
            end
            event_list = table(years,months,days,events,scenarios,plot);
            event_list.Properties.VariableNames = {'Year','Month','Day','Event','Scenario','Plot'};
            set(app.EventTable,'Data',event_list);
            
            app.startdateSp.Value = years(1);
            app.enddateSp.Value = years(end);
        end

        % Menu selected function: FaultMenu
        function import_faults(app, event)
            set(app.UIFig,'Visible','off')
            app.fault_dir = uigetdir;
            set(app.UIFig,'Visible','on')
            files = ls([app.fault_dir '\*.csv']);
            n_faults = size(files,1);
            fault_names = strings(n_faults,1);
            plot = true(n_faults,1);
            for i = 1:n_faults
                file_name = strrep(files(i,:),' ',''); %extract fault name from csv file names
                fault_name = convertCharsToStrings(file_name(1:end-4));
                fault_names(i) = fault_name;
            end            
            set(app.FaultTable,'Data',table(fault_names,plot));
        end

        % Menu selected function: CalcMenu
        function CalcStress(app, event)
            addpath source\
            plot_cumulative_stress3
        end

        % Menu selected function: InterseismicMenu
        function imp_interseis(app, event)
            set(app.UIFig,'Visible','off')
            [file,path] = uigetfile('*.csv');
            set(app.UIFig,'Visible','on')
            app.interseis_file = fullfile(path,file);
            set(app.interseismicCB,'Enable','on','Value',true);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFig = uifigure('Visible', 'off');
            app.UIFig.Name = '3D-Faults Stress Plotter';
            app.ImportMenu = uimenu(app.UIFig);
            app.ImportMenu.Text = 'Import';
            app.CoulombMenu = uimenu(app.ImportMenu);
            app.CoulombMenu.MenuSelectedFcn = createCallbackFcn(app, @import_coulomb, true);
            app.CoulombMenu.Text = 'Coulomb files (directory)';
            app.FaultMenu = uimenu(app.ImportMenu);
            app.FaultMenu.MenuSelectedFcn = createCallbackFcn(app, @import_faults, true);
            app.FaultMenu.Text = 'Fault geometry (directory)';
            app.InterseismicMenu = uimenu(app.ImportMenu);
            app.InterseismicMenu.MenuSelectedFcn = createCallbackFcn(app, @imp_interseis, true);
            app.InterseismicMenu.Text = 'Interseismic CST (csv)';
            app.RunMenu = uimenu(app.UIFig);
            app.RunMenu.Text = 'Run';
            app.CalcMenu = uimenu(app.RunMenu);
            app.CalcMenu.MenuSelectedFcn = createCallbackFcn(app, @CalcStress, true);
            app.CalcMenu.Text = 'Calc cumulative stress';
            app.TabGroup = uitabgroup(app.UIFig);
            app.EarthquakesTab = uitab(app.TabGroup);
            app.EarthquakesTab.Title = 'Earthquakes';
            app.EventTable = uitable(app.EarthquakesTab);
            app.EventTable.ColumnName = {'Year'; 'Month'; 'Day'; 'Event'; 'Scenario'; 'Plot'};
            app.EventTable.RowName = {};
            app.EventTable.ColumnEditable = [true true true true true true];
            app.FaultsTab = uitab(app.TabGroup);
            app.FaultsTab.Title = 'Faults';
            app.FaultTable = uitable(app.FaultsTab);
            app.FaultTable.ColumnName = {'fault'; 'plot'};
            app.FaultTable.RowName = {};
            app.FaultTable.ColumnEditable = [true true];
            app.CumulativeStressPanel = uipanel(app.UIFig);
            app.CumulativeStressPanel.Title = 'Cumulative Stress';
            app.startdateSpinnerLabel = uilabel(app.CumulativeStressPanel);
            app.startdateSpinnerLabel.Text = 'start date';
            app.startdateSp = uispinner(app.CumulativeStressPanel);
            app.startdateSp.Tooltip = {'Calc. cumulative stress between start and end date.'};
            app.enddateSpinnerLabel = uilabel(app.CumulativeStressPanel);
            app.enddateSpinnerLabel.Text = 'end date';
            app.enddateSp = uispinner(app.CumulativeStressPanel);
            app.enddateSp.Tooltip = {'Calc. cumulative stress between start and end date.'};
            app.interseismicCB = uicheckbox(app.CumulativeStressPanel);
            app.interseismicCB.Enable = 'off';
            app.interseismicCB.Tooltip = {'Add annual interseismic stress. Requires a Coulomb csv file with interseismic CST.'};
            app.interseismicCB.Text = 'interseismic stress';
            app.prestressMPaSpinnerLabel = uilabel(app.CumulativeStressPanel);
            app.prestressMPaSpinnerLabel.Text = 'pre-stress (MPa)';
            app.prestressSp = uispinner(app.CumulativeStressPanel);
            app.prestressSp.Tooltip = {'stress at beginning of model, default zero'};
            app.PlotPanel = uipanel(app.UIFig);
            app.PlotPanel.Title = 'Plot';
            app.plot3dCB = uicheckbox(app.PlotPanel);
            app.plot3dCB.Text = 'plot 3D';
            app.plotdateCB = uicheckbox(app.PlotPanel);
            app.plotdateCB.Text = 'plot date on figure';
            app.AutomaticallyexportfiguresDropDownLabel = uilabel(app.PlotPanel);
            app.AutomaticallyexportfiguresDropDownLabel.Text = 'Automatically export figures';
            app.ExpFigDD = uidropdown(app.PlotPanel);
            app.ExpFigDD.Items = {'none', 'annually', 'after each event', 'end of model'};
            app.ExpFigDD.Tooltip = {'Saves figures as .png'};
            app.ExpFigDD.Value = 'none';
            app.colormapDropDownLabel = uilabel(app.PlotPanel);
            app.colormapDropDownLabel.Text = 'colormap:';
            app.colormapDD = uidropdown(app.PlotPanel);
            app.colormapDD.Items = {'vik', 'anatolia'};
            app.colormapDD.Value = 'vik';
            % Show the figure after all components are created
            app.UIFig.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stress_plotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFig)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFig)
        end
    end
end